classdef lev_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        Label_8                matlab.ui.control.Label
        ButtonCheck            matlab.ui.control.Button
        TableCheckOut          matlab.ui.control.Table
        Panel2                 matlab.ui.container.Panel
        Delay                  matlab.ui.control.NumericEditField
        Label_7                matlab.ui.control.Label
        LabelStatus            matlab.ui.control.Label
        Lamp                   matlab.ui.control.Lamp
        LabelCurrentOperation  matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        LabelCurrent_m         matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        start_alg              matlab.ui.control.Button
        Label_2                matlab.ui.control.Label
        TableOutput            matlab.ui.control.Table
        Panel1                 matlab.ui.container.Panel
        CreateMatrixButton     matlab.ui.control.Button
        TableInput             matlab.ui.control.Table
        Label                  matlab.ui.control.Label
        EditTextTeplCoef       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditTextTeplCoef
        function EditTextTeplCoefValueChanged(app, event)
            value = app.EditTextTeplCoef.Value;
            app.CreateMatrixButton.Enable = ~isempty(value);
        end

        % Button pushed function: CreateMatrixButton
        function CreateMatrixButtonPushed(app, event)
            coefficientsText = app.EditTextTeplCoef.Value;
            app.ButtonCheck.Enable = "off";
            app.TableOutput.Data = [];
            app.TableCheckOut.Data = [];
            app.LabelStatus.Text = "Ожидание";
            app.Lamp.Color = [1,1,1];
            app.LabelCurrent_m.Text = sprintf("%d из %d",0,0);
            try
                coefficients = str2num(coefficientsText);
                toeplitzMatrix = toeplitz(coefficients);
                app.TableInput.Data = toeplitzMatrix;
                app.start_alg.Enable = "on";
                
            catch
                uialert(app.UIFigure, 'Что-то не так ввели (наверное)', 'Ошибка');
                app.start_alg.Enable = "off";
                app.TableInput.Data = [];
            end
        end

        % Value changing function: EditTextTeplCoef
        function EditTextTeplCoefValueChanging(app, event)
  
        end

        % Button pushed function: start_alg
        function start_algButtonPushed(app, event)
            input = app.TableInput.Data;
            app.TableCheckOut.Data = [];
            app.TableOutput.Data = [];
            try
                app.Lamp.Color = [1,1,0];
                delay = app.Delay.Value / 100;
                app.LabelStatus.Text = "Выполняется";
                output = lev_modified(input, app.LabelCurrent_m, app.LabelCurrentOperation, delay);
                app.TableOutput.Data = output;
                app.Lamp.Color = [0,1,0];
                app.LabelStatus.Text = "Готово";
                app.LabelCurrentOperation.Text = "Ничего";
                app.ButtonCheck.Enable = "on";
            catch
                app.ButtonCheck.Enable = "off";
                uialert(app.UIFigure, 'Что-то пошло не так в работе алгоритма.', 'Ошибка');
                app.Lamp.Color = [1,0,0];
                app.LabelStatus.Text = "Ошибка";
                app.LabelCurrentOperation.Text = "Ничего";
            end
        end

        % Button pushed function: ButtonCheck
        function ButtonCheckPushed(app, event)
            app.TableCheckOut.Data = app.TableInput.Data * app.TableOutput.Data;
            formattedData = arrayfun(@(x) sprintf('%.3f', x), app.TableCheckOut.Data, 'UniformOutput', false);
            app.TableCheckOut.Data = formattedData;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel1 = uipanel(app.UIFigure);
            app.Panel1.Title = 'Исходные данные';
            app.EditTextTeplCoef = uieditfield(app.Panel1, 'text');
            app.EditTextTeplCoef.ValueChangedFcn = createCallbackFcn(app, @EditTextTeplCoefValueChanged, true);
            app.EditTextTeplCoef.ValueChangingFcn = createCallbackFcn(app, @EditTextTeplCoefValueChanging, true);
            app.Label = uilabel(app.Panel1);
            app.Label.Text = {'Коэффициенты для создания матрицы '; '(через пробел или запятую)'};
            app.TableInput = uitable(app.Panel1);
            app.TableInput.ColumnName = '';
            app.TableInput.RowName = {};
            app.CreateMatrixButton = uibutton(app.Panel1, 'push');
            app.CreateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMatrixButtonPushed, true);
            app.CreateMatrixButton.Enable = 'off';
            app.CreateMatrixButton.Text = 'Создать матрицу Тёплица';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Нахождение обратной матрицы (Алоритм Левинсона-Дарбина)';
            app.TableOutput = uitable(app.Panel2);
            app.TableOutput.ColumnName = '';
            app.TableOutput.RowName = {};
            app.Label_2 = uilabel(app.Panel2);
            app.Label_2.Text = '(результат работы алгоритма)';
            app.start_alg = uibutton(app.Panel2, 'push');
            app.start_alg.ButtonPushedFcn = createCallbackFcn(app, @start_algButtonPushed, true);
            app.start_alg.Enable = 'off';
            app.start_alg.Text = 'Запустить алгоритм';
            app.Label_4 = uilabel(app.Panel2);
            app.Label_4.Text = 'Информация:';
            app.Label_5 = uilabel(app.Panel2);
            app.Label_5.Text = 'Текущая итерация:';
            app.LabelCurrent_m = uilabel(app.Panel2);
            app.LabelCurrent_m.Text = '0 из 0';
            app.Label_6 = uilabel(app.Panel2);
            app.Label_6.Text = 'Выполняется операция:';
            app.LabelCurrentOperation = uilabel(app.Panel2);
            app.LabelCurrentOperation.Text = 'Ничего';
            app.Lamp = uilamp(app.Panel2);
            app.LabelStatus = uilabel(app.Panel2);
            app.LabelStatus.Text = 'Ожидание';
            app.Label_7 = uilabel(app.Panel2);
            app.Label_7.Text = 'Искусственная задержка (мс)';
            app.Delay = uieditfield(app.Panel2, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Проверка';
            app.TableCheckOut = uitable(app.Panel);
            app.TableCheckOut.ColumnName = '';
            app.TableCheckOut.RowName = {};
            app.ButtonCheck = uibutton(app.Panel, 'push');
            app.ButtonCheck.ButtonPushedFcn = createCallbackFcn(app, @ButtonCheckPushed, true);
            app.ButtonCheck.Enable = 'off';
            app.ButtonCheck.Text = 'Проверить результат';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '(Умножение исходной матрицы на обратную)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lev_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end